classdef DoAnPPT_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TimNghiem                     matlab.ui.container.Tab
        SlnlpLabel                    matlab.ui.control.Label
        KtqunghimLabel                matlab.ui.control.Label
        ChnphngphptnhLabel            matlab.ui.control.Label
        NhpkhongsaisLabel             matlab.ui.control.Label
        bLabel                        matlab.ui.control.Label
        aLabel                        matlab.ui.control.Label
        SoLanLap                      matlab.ui.control.NumericEditField
        KetQuaNghiem                  matlab.ui.control.NumericEditField
        NhapSaiSo                     matlab.ui.control.NumericEditField
        bEditField                    matlab.ui.control.NumericEditField
        aEditField                    matlab.ui.control.NumericEditField
        KhongphnlinghimLabel          matlab.ui.control.Label
        VeButton                      matlab.ui.control.Button
        ChonPPT                       matlab.ui.control.DropDown
        NhapPT                        matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        NiSuyTab                      matlab.ui.container.Tab
        xNoiSuy                       matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        yNoiSuy                       matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        KetQuaDaThucNoiSuy            matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        VeNoiSuy                      matlab.ui.control.Button
        KetQuaNoiSuy                  matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        GiaTriCanNoiSuy               matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        ChonPPNoiSuy                  matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        Tab3                          matlab.ui.container.Tab
        Tab4                          matlab.ui.container.Tab
        Tab5                          matlab.ui.container.Tab
        Tab6                          matlab.ui.container.Tab
    end

    
    methods (Access = public)
        
    function [c, n] = chiadoi(app,fx, a ,b ,saiso)
        n = 0;
        fxi = str2func(['@(x)', fx]);
        while true 
        c = (a + b)/2;
        if(fxi(a)*fxi(c) < 0)
            b = c;
        else 
            a = c;
        end
        n = n + 1;
        if abs(b-a) < saiso
            break;
        end
        end
        end

    function[ x1, n]= lap(app,fx, a, saiso) 
        n = 0;  
        lambda = 1;   % có thể chỉnh nếu không hội tụ
    
        % fx là chuỗi → đổi thành function
        f = str2func(['@(x)', fx]);
    
        % hàm lặp
        fp = @(x) x - lambda * f(x);
        x0 = a; 
        for i = 1:2000 
        x1 = fp(x0); 
        n = n+1; 
        if abs(x1 - x0) < saiso 
        break; 
        end 
        x0 = x1; 
        end 
        end

    function [x1, n] = Newton(app,fx, a, b, saiso) 
        syms x; 
        f = str2func(['@(x)', fx]);        
        df = matlabFunction(diff(str2sym(fx), x));   
        f2 = matlabFunction(diff(diff(str2sym(fx), x)));  
        if f(a) * f2(a) > 0 
        x0 = a; 
        else 
        x0 = b; 
        end 
        n = 0; 
        while true 
        n = n + 1; 
        x1 = x0 - f(x0) / df(x0);   
        if abs(x1 - x0) < saiso 
        break; 
        end 
        x0 = x1; 
        if df(x0) == 0 
        warning('Đạo hàm bằng 0 tại x = %.6f, phương pháp dừng.', x0); 
        break; 
        end 
        end 
    end 


    %--Nội suy--
    function result = Lagrange(app,xa, ya, x) 
    n = length(xa); 
    sum = 0;
    for i = 1:n 
        product = ya(i); 
        for j = 1:n 
            if i ~= j 
                product = product * (x - xa(j)) / (xa(i) - xa(j)); 
            end 
        end 
        sum = sum + product; 
    end   
    result = sum; 
    end

    function P = LagrangeSym(app,xa, ya)    
    syms x; 
    n = length(xa); 
    P = 0; 
    for i = 1:n 
    L = 1;  
    for j = 1:n 
    if i ~= j 
    L = L * (x - xa(j)) / (xa(i) - xa(j)); 
    end 
    end 
    P = P + ya(i) * L; 
    end 
    P = simplify(P); 
    end

    function d = DividedDifference(app,xa, ya) 
    n = length(ya); 
    d_table = zeros(n, n); 
    d_table(:, 1) = ya(:);  
    for j = 2:n 
    for i = 1:n-j+1 
    d_table(i, j) = (d_table(i+1, j-1) - d_table(i, j-1)) / (xa(i+j-1) - xa(i)); 
    end 
    end 
    d = d_table(1, :); 
    end

    function result = NewtonForm(app,xa, da, x) 
    n = length(da); 
    result = da(n); 
    for i = n-1:-1:1 
    result = result * (x - xa(i)) + da(i); 
    end 
    end

    function result = NewtonInterpolation(app,xa, ya, x) 
    da = app.DividedDifference(xa, ya);     
    result = app.NewtonForm(xa, da, x); 
    end

    function P = NewtonSym(app,xa, ya) 
    syms x; 
    n = length(xa); 
    d = app.DividedDifference(xa, ya); 
    P = d(1); 
    term = 1; 
    for i = 2:n 
    term = term * (x - xa(i-1)); 
    P = P + d(i) * term; 
    end  
    P = simplify(P); 
    end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VeButton
        function VeButtonPushed(app, event)
            fx = app.NhapPT.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            saiso = app.NhapSaiSo.Value;
            pp = app.ChonPPT.Value;

            switch pp
        case 'Chia đôi'
            [nghiem, solanlap] = app.chiadoi(fx,a,b,saiso);

        case 'Lặp'
            [nghiem, solanlap] = app.lap(fx,a,saiso);

         case'Newton'
            [nghiem, solanlap] = app.Newton(fx,a,b,saiso);
    end

    % Vẽ đồ thị hội tụ
    plot(app.UIAxes, solanlap, nghiem,'-o');
    xlabel(app.UIAxes,'Số lần lặp');
    ylabel(app.UIAxes,'Giá trị nghiệm');
    grid(app.UIAxes,'on');

    % Hiển thị kết quả cuối
    app.KetQuaNghiem.Value = nghiem(end);
    app.SoLanLap.Value = solanlap(end);
        end

        % Button pushed function: VeNoiSuy
        function VeNoiSuyButtonPushed(app, event)

            x_str = app.xNoiSuy.Value;   % lấy chuỗi người dùng nhập
            x = str2num(x_str);         %#ok<ST2NM>  chuyển sang mảng số
            y_str = app.yNoiSuy.Value;
            y = str2num(y_str);
            x_new = app.GiaTriCanNoiSuy.Value;
            pp = app.ChonPPNoiSuy.Value;

            switch pp
                case 'Lagrange'
            result = app.Lagrange(x,y,x_new);
            result_sym = app.LagrangeSym(x,y);

            x_plot = linspace(min(x), max(x), 500);
            y_plot = arrayfun(@(xx) app.Lagrange(x, y, xx), x_plot);

            % Vẽ điểm dữ liệu ban đầu
            plot(app.UIAxes2, x, y, 'ro', ...
                'MarkerSize', 8, 'LineWidth', 1.5);
            hold(app.UIAxes2, 'on');

            % Vẽ đường nội suy Lagrange
            plot(app.UIAxes2, x_plot, y_plot, 'b-', ...
                'LineWidth', 2);

            % Đánh dấu điểm nội suy x_new
            plot(app.UIAxes2, x_new, result, 'ks', ...
                'MarkerSize', 8, 'LineWidth', 1.5);

            legend(app.UIAxes2, ...
                'Điểm dữ liệu ban đầu', ...
                'Hàm nội suy Lagrange', ...
                'Giá trị nội suy', ...
                'Location', 'best');

            xlabel(app.UIAxes2, 'x');
            ylabel(app.UIAxes2, 'y');
            title(app.UIAxes2, 'Nội suy Lagrange');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');

                case 'Newton'
            result = app.NewtonInterpolation(x, y, x_new);
            result_sym = app.NewtonSym(x, y);
            

            x_plot = linspace(min(x), max(x), 500);
            y_plot = arrayfun(@(xx) app.NewtonInterpolation(x, y, xx), x_plot);

            % Vẽ điểm dữ liệu ban đầu
            plot(app.UIAxes2, x, y, 'ro', ...
                'MarkerSize', 8, 'LineWidth', 1.5);
            hold(app.UIAxes2, 'on');

            % Vẽ đường nội suy Lagrange
            plot(app.UIAxes2, x_plot, y_plot, 'b-', ...
                'LineWidth', 2);

            % Đánh dấu điểm nội suy x_new
            plot(app.UIAxes2, x_new, result, 'ks', ...
                'MarkerSize', 8, 'LineWidth', 1.5);

            legend(app.UIAxes2, ...
                'Điểm dữ liệu ban đầu', ...
                'Hàm nội suy Newton', ...
                'Giá trị nội suy', ...
                'Location', 'best');

            xlabel(app.UIAxes2, 'x');
            ylabel(app.UIAxes2, 'y');
            title(app.UIAxes2, 'Nội suy Newton');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');
            end
        
        % Hiển thị kết quả cuối
        app.KetQuaDaThucNoiSuy.Value = char(result_sym);
        app.KetQuaNoiSuy.Value = result(end);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimNghiem = uitab(app.TabGroup);
            app.TimNghiem.Title = 'Tìm Nghiệm';
            app.UIAxes = uiaxes(app.TimNghiem);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.TimNghiem);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapPT = uieditfield(app.TimNghiem, 'text');
            app.NhapPT.Value = 'x - sin(x) -0.25';
            app.ChonPPT = uidropdown(app.TimNghiem);
            app.ChonPPT.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.ChonPPT.Value = 'Chia đôi';
            app.VeButton = uibutton(app.TimNghiem, 'push');
            app.VeButton.ButtonPushedFcn = createCallbackFcn(app, @VeButtonPushed, true);
            app.VeButton.Text = 'Vẽ';
            app.KhongphnlinghimLabel = uilabel(app.TimNghiem);
            app.KhongphnlinghimLabel.Text = 'Khoảng phân li nghiệm';
            app.aEditField = uieditfield(app.TimNghiem, 'numeric');
            app.aEditField.Value = -1;
            app.bEditField = uieditfield(app.TimNghiem, 'numeric');
            app.bEditField.Value = 2;
            app.NhapSaiSo = uieditfield(app.TimNghiem, 'numeric');
            app.NhapSaiSo.Value = 0.005;
            app.KetQuaNghiem = uieditfield(app.TimNghiem, 'numeric');
            app.SoLanLap = uieditfield(app.TimNghiem, 'numeric');
            app.aLabel = uilabel(app.TimNghiem);
            app.aLabel.Text = 'a';
            app.bLabel = uilabel(app.TimNghiem);
            app.bLabel.Text = 'b';
            app.NhpkhongsaisLabel = uilabel(app.TimNghiem);
            app.NhpkhongsaisLabel.Text = 'Nhập khoảng sai số';
            app.ChnphngphptnhLabel = uilabel(app.TimNghiem);
            app.ChnphngphptnhLabel.Text = 'Chọn phương pháp tính';
            app.KtqunghimLabel = uilabel(app.TimNghiem);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm';
            app.SlnlpLabel = uilabel(app.TimNghiem);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.NiSuyTab = uitab(app.TabGroup);
            app.NiSuyTab.Title = 'Nội Suy';
            app.UIAxes2 = uiaxes(app.NiSuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.NiSuyTab);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy';
            app.ChonPPNoiSuy = uidropdown(app.NiSuyTab);
            app.ChonPPNoiSuy.Items = {'Lagrange', 'Newton'};
            app.ChonPPNoiSuy.Value = 'Lagrange';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.GiaTriCanNoiSuy = uieditfield(app.NiSuyTab, 'numeric');
            app.GiaTriCanNoiSuy.Value = 0.11;
            app.KtqunisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KetQuaNoiSuy = uieditfield(app.NiSuyTab, 'numeric');
            app.VeNoiSuy = uibutton(app.NiSuyTab, 'push');
            app.VeNoiSuy.ButtonPushedFcn = createCallbackFcn(app, @VeNoiSuyButtonPushed, true);
            app.VeNoiSuy.Text = 'Vẽ';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KetQuaDaThucNoiSuy = uieditfield(app.NiSuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.yNoiSuy = uieditfield(app.NiSuyTab, 'text');
            app.yNoiSuy.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpdliuxEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.xNoiSuy = uieditfield(app.NiSuyTab, 'text');
            app.xNoiSuy.Value = '[0.1 0.2 0.3 0.4]';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoAnPPT_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end